classdef AutoCalibrator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        CalibrationParametersPanel  matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        WeightMapPanel              matlab.ui.container.Panel
        WeightMapImage              matlab.ui.control.Image
        CoordinateTransformPanel    matlab.ui.container.Panel
        TransformParamLabel         matlab.ui.control.Label
        ETLCurrentRawValuePanel     matlab.ui.container.Panel
        ETLCurrentLabel             matlab.ui.control.Label
        ParametersPanel             matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        CalibrationPatternPanel     matlab.ui.container.Panel
        GridLayout9                 matlab.ui.container.GridLayout
        CalibPatternScaleSpinner    matlab.ui.control.Spinner
        FirstCalibSpotYSpinner      matlab.ui.control.Spinner
        FirstCalibSpotXSpinner      matlab.ui.control.Spinner
        PatternScaleLabel           matlab.ui.control.Label
        FirstSpotYmLabel            matlab.ui.control.Label
        FirstSpotXmLabel            matlab.ui.control.Label
        SavedFolderPanel            matlab.ui.container.Panel
        GridLayout5_2               matlab.ui.container.GridLayout
        FolderSelectButton          matlab.ui.control.Button
        CurrentFolderTextArea       matlab.ui.control.TextArea
        FolderSaveButton            matlab.ui.control.Button
        FolderOpenButton            matlab.ui.control.Button
        SLMPanel                    matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        SLMHeightLabel              matlab.ui.control.Label
        SLMWidthLabel               matlab.ui.control.Label
        SLMPixelPitchLabel          matlab.ui.control.Label
        PixelHeightLabel            matlab.ui.control.Label
        PixelWidthLabel             matlab.ui.control.Label
        PixelPitchmLabel            matlab.ui.control.Label
        OpticalSystemPanel          matlab.ui.container.Panel
        GridLayout4_2               matlab.ui.container.GridLayout
        FocalLengthSpinner          matlab.ui.control.Spinner
        FocalLengthoflensmLabel     matlab.ui.control.Label
        LaserPanel                  matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        WaveLengthSpinner           matlab.ui.control.Spinner
        WaveLengthnmLabel           matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        AutoTab                     matlab.ui.container.Tab
        GridLayout6                 matlab.ui.container.GridLayout
        GridLayout10                matlab.ui.container.GridLayout
        AutoCalibrationStatusImage  matlab.ui.control.Image
        AutoCalibrationStatusAxes   matlab.ui.control.UIAxes
        GridLayout7                 matlab.ui.container.GridLayout
        GridLayout8                 matlab.ui.container.GridLayout
        StopAutoCalibrationButton   matlab.ui.control.Button
        StartAutoCalibrationButton  matlab.ui.control.Button
        StatusPanel                 matlab.ui.container.Panel
        CalibrationStatusTree       matlab.ui.container.Tree
        ETLScanTab                  matlab.ui.container.Tab
        CoordinateCorrectionTab     matlab.ui.container.Tab
        WeightMapTab                matlab.ui.container.Tab
    end

    
    properties (Access = private)
        SLM devices.slm.PhaseSLM
        ETL
        Camera devices.camera.Camera
        Working logical
        CurrentTask = [] %parallel.Future 
        Calibrator = [] %calibration.PositionCalibrator
        ETLValue = []
        WeightMap = [] %calibration.IntensityWeightMap
    end
    
    methods (Access = private)
        function success = saveFolder(app,folderpath)
            if exist(folderpath,"dir") ~= 7
                success = false;
                return;
            end
            success = true;
            savedData = table;
            savedData.WaveLength=app.WaveLengthSpinner.Value;
            savedData.FocalLength=app.FocalLengthSpinner.Value;
            savedData.SpotX=app.FirstCalibSpotXSpinner.Value;
            savedData.SpotY=app.FirstCalibSpotYSpinner.Value;
            savedData.PatternScale=app.CalibPatternScaleSpinner.Value;
            writetable(savedData,folderpath+"/config.xml")
            if ~isempty(app.ETLValue)
                writematrix(app.ETLValue,folderpath+"/etl_current_raw.csv");
            end
            if ~isempty(app.Calibrator)
                app.Calibrator.saveTo(folderpath+"/pos_calibrator.csv");
            end
            if ~isempty(app.WeightMap)
                app.WeightMap.saveTo(folderpath+"/weight_map.csv");
            end
        end

        function success = openFolder(app,folderpath)
            arguments(Input)
                app tools.gui.AutoCalibrator
                folderpath
            end
            if exist(folderpath,"dir") ~= 7
                success = false;
                return;
            end
            success = true;
            fileconfig=folderpath+"/config.xml";
            if isfile(fileconfig)
                savedData=readtable(fileconfig);
                app.WaveLengthSpinner.Value=savedData.WaveLength;
                app.FocalLengthSpinner.Value=savedData.FocalLength;
                app.FirstCalibSpotXSpinner.Value=savedData.SpotX;
                app.FirstCalibSpotYSpinner.Value=savedData.SpotY;
                app.CalibPatternScaleSpinner.Value=savedData.PatternScale;
            end
            fileetl=folderpath+"/etl_current_raw.csv";
            if isfile(fileetl)
                app.setETLValue(readmatrix(fileetl));
            end
            filecalib=folderpath+"/pos_calibrator.csv";
            if isfile(filecalib)
                app.setCalibrator(calibration.PositionCalibrator.loadFrom(filecalib));
            end
            filewm=folderpath+"/weight_map.csv";
            if isfile(filewm)
                app.setWeightMap(calibration.IntensityWeightMap.loadFrom(filewm));
            end
        end

        function setETLValue(app,value)
            app.ETLValue = value;
            app.ETLCurrentLabel.Text = string(value);
        end
        function setCalibrator(app,calibrator)
            app.Calibrator=calibrator;
            %
        end
        function setWeightMap(app,weightmap)
            app.WeightMap=weightmap;
            wm = app.WeightMap.WeightMap;
            viewImg = cat(3,wm,wm,wm);
            app.WeightMapImage.ImageSource = viewImg;
        end
        
        function phasemap = getEmptyPhaseMap(app)
            arguments(Output)
                phasemap devices.slm.PhaseMap
            end
            [ysize,xsize]=app.SLM.getPixelArraySize();
            pixelpitch_um=app.SLM.getPixelPitch();
            phasemap=devices.slm.PhaseMap(xsize,ysize,pixelpitch_um,pixelpitch_um,app.FocalLengthSpinner.Value,app.WaveLengthSpinner.Value);
        end

        % === ETL Scan

        function calibration_etlScan_Start(app,afterFunction)
            arguments(Input)
                app tools.gui.AutoCalibrator
                afterFunction = []
            end
            etlscanner = calibration.ETLScanner( ...
                app.Camera, ...
                app.SLM, ...
                app.ETL, ...
                app.FirstCalibSpotXSpinner.Value, ...
                app.FirstCalibSpotYSpinner.Value, ...
                0, ...
                app.FocalLengthSpinner.Value, ...
                app.WaveLengthSpinner.Value, ...
                -4096:250:4095 ...
            );

            app.calibration_etlScan_Call(app,etlscanner,true,[],afterFunction);
        end

        function calibration_etlScan_Call(app,etlScanner,hasNext,image,afterFunction)
            arguments(Input)
                app tools.gui.AutoCalibrator
                etlScanner calibration.ETLScanner
                hasNext logical
                image
                afterFunction
            end
            % In Main thread
            scores=etlScanner.getCurrentScores;
            if hasNext
                f=parfeval(backgroundPool,@app.calibration_etlScan,2,etlScanner);
                app.CurrentTask=f;
                afterEach(f,@(hN,img)app.calibration_etlScan_Call(etlScanner,hN,img),0)
            else
                app.ETL.setCurrentRaw(app.ETLValue);
                app.setETLValue(etlScanner.getResult);
                if ~isempty(afterFunction)
                    afterFunction();
                end
            end
            if ~isempty(image)
                filt=fspecial("average",3);
                viewImg=rescale(imfilter(double(image),filt));
                app.AutoCalibrationStatusImage.ImageSource = cat(3,viewImg,viewImg,viewImg);
                plot(app.AutoCalibrationStatusAxes,1:length(scores),scores);
            end
        end
        function [hasNext,image] = calibration_etlScan(~,etlScanner)
            arguments(Input)
                ~
                etlScanner calibration.ETLScanner
            end
            % In Worker thread
            [hasNext,image]=etlScanner.next();
        end

        % === Affine Test

        function calibration_testAffineTransform(app,afterFunction)
            arguments(Input)
                app tools.gui.AutoCalibrator
                afterFunction = []
            end
            affineTester = calibration.AffineTransformTester( ...
                app.Camera, ...
                app.SLM, ...
                app.FirstCalibSpotXSpinner.Value, ...
                app.FirstCalibSpotYSpinner.Value, ...
                0, ...
                app.FocalLengthSpinner.Value, ...
                app.WaveLengthSpinner.Value, ...
                app.CalibPatternScaleSpinner.Value ...
            );
            f=parfeval(backgroundPool,@affineTester.tryByTriangle,4);
            app.CurrentTask=f;
            f.afterEach(@(pc,img,ds,es)app.calibration_testAffineTransformAfter(affineTester,pc,img,ds,es,afterFunction));
        end
        function calibration_testAffineTransformAfter(app,affineTester,calibrator,image,detectedSpots,expectedSpots,afterFunction)
            viewImg=app.addMarkerToImage(image,detectedSpots,expectedSpots);
            app.AutoCalibrationStatusImage.ImageSource = viewImg;

            f=parfeval(backgroundPool,@affineTester.tryByHexagon,4,calibrator);
            app.CurrentTask=f;
            f.afterEach(@(pc,img,ds,es)app.calibration_testAffineTransformHexAfter(affineTester,pc,img,ds,es,afterFunction));
        end
        function calibration_testAffineTransformHexAfter(app,calibrator,image,detectedSpots,expectedSpots,afterFunction)
            viewImg=app.addMarkerToImage(image,detectedSpots,expectedSpots);
            app.AutoCalibrationStatusImage.ImageSource = viewImg;

            app.setCalibrator(calibrator);
            if ~isempty(afterFunction)
                afterFunction();
            end
        end

        function viewImg=addMarkerToImage(~,image,detectedSpots,expectedSpots)
            filt=fspecial("average",3);
            img=rescale(imfilter(double(image),filt));
            viewImg = cat(3,img,img,img);
            height=size(img,1);
            width=size(img,2);
            markRadius=10;
            markWidth=1;
            for i=size(detectedSpots,1)
                spotPos=round(detectedSpots(i,:));
                viewImg(1,max([1,spotPos(1)-markRadius]):min([height,spotPos(1)+markRadius],max([1,spotPos(2)-markWidth]):min([width,spotPos(2)+markWidth])))=1;
                viewImg(1,max([1,spotPos(1)-markWidth]):min([height,spotPos(1)+markWidth],max([1,spotPos(2)-markRadius]):min([width,spotPos(2)+markRadius])))=1;
            end
            for i=size(expectedSpots,1)
                spotPos=round(expectedSpots(i,:));
                viewImg(2,max([1,spotPos(1)-markRadius]):min([height,spotPos(1)+markRadius],max([1,spotPos(2)-markWidth]):min([width,spotPos(2)+markWidth])))=1;
                viewImg(2,max([1,spotPos(1)-markWidth]):min([height,spotPos(1)+markWidth],max([1,spotPos(2)-markRadius]):min([width,spotPos(2)+markRadius])))=1;
            end
        end

        % === scan weightmap
        function calibration_scanWeightMap_Start(app,afterFunction)
            arguments(Input)
                app tools.gui.AutoCalibrator
                afterFunction = []
            end
            if isempty(app.Calibrator)
                return;
            end
            weightmapScanner = calibration.WeightMapScanner( ...
                app.Camera, ...
                app.SLM, ...
                app.Calibrator, ...
                app.FirstCalibSpotXSpinner.Value, ...
                app.FirstCalibSpotYSpinner.Value ...
            );
            calibration_scanWeightMap_Call(app,weightmapScanner,true,[],afterFunction);
        end
        function calibration_scanWeightMap_Call(app,weightmapScanner,hasNext,image,afterFunction)
            arguments(Input)
                app tools.gui.AutoCalibrator
                weightmapScanner calibration.WeightMapScanner
                hasNext logical
                image
                afterFunction = []
            end
            % In Main thread
            if hasNext
                f=parfeval(backgroundPool,@app.calibration_scanWeightMap,2,weightmapScanner);
                app.CurrentTask=f;
                afterEach(f,@(hN,img)app.calibration_etlScan_Call(weightmapScanner,hN,img),0)
            else
                app.setWeightMap(weightmapScanner.getWeightMap);

                if ~isempty(afterFunction)
                    afterFunction();
                end
            end
            if ~isempty(image)
                filt=fspecial("average",3);
                viewImg=rescale(imfilter(double(image),filt));
                app.AutoCalibrationStatusImage.ImageSource = cat(3,viewImg,viewImg,viewImg);
            end
        end
        function [hasNext,image] = calibration_scanWeightMap(~,weightmapScanner)
            arguments(Input)
                ~
                weightmapScanner calibration.WeightMapScanner
            end
            % In Worker thread
            [hasNext,image]=weightmapScanner.next();
        end

        % ===

        function startAutoCalibration(app)
            if ~isempty(app.CurrentTask)
                cancel(app.CurrentTask);
                app.CurrentTask=[];
            end
            app.StartAutoCalibrationButton.Enable = "off";
            app.StopAutoCalibrationButton.Enable = "on";
        end
        function stopAutoCalibration(app)
            if ~isempty(app.CurrentTask)
                cancel(app.CurrentTask);
                app.CurrentTask=[];
            end
            app.StartAutoCalibrationButton.Enable="on";
            app.StopAutoCalibrationButton.Enable="off";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, slm, etl, camera, foldername)
            arguments(Input)
                app tools.gui.AutoCalibrator
                slm devices.slm.PhaseSLM
                etl devices.etl.OptotuneLensDriver
                camera devices.camera.Camera
                foldername = "./save/calibration/1"
            end
            app.SLM = slm;
            app.ETL = etl;
            app.Camera = camera;
            [ysize,xsize]=app.SLM.getPixelArraySize();
            pixelpitch_um=app.SLM.getPixelPitch();
            app.SLMHeightLabel.Text = ysize;
            app.SLMWidthLabel.Text = xsize;
            app.SLMPixelPitchLabel = pixelpitch_um;
            app.CurrentFolderTextArea.Value = foldername;
            app.AutoCalibrationStatusAxes.Title="";
            app.AutoCalibrationStatusAxes.XLabel="";
        end

        % Button pushed function: FolderOpenButton
        function FolderOpenButtonPushed(app, event)
            mkdir("./save","calibration");
            path=uigetdir("./save/calibration");
            if path == 0
                return;
            end
            if app.openFolder(path)
                app.CurrentFolderTextArea.Value = path;
            else
                fig=uifigure;
                uialert(fig,"This folder has correct setting information. Please select correct folder.","Invalid Folder")
            end
        end

        % Button pushed function: FolderSelectButton
        function FolderSelectButtonPushed(app, event)
            mkdir("./save","calibration");
            path=uigetdir("./save/calibration");
            if path == 0
                return;
            end
            app.CurrentFolderTextArea.Value = path;
        end

        % Button pushed function: FolderSaveButton
        function FolderSaveButtonPushed(app, event)
            if ~app.saveFolder(app.CurrentFolderTextArea.Value)
                fig=uifigure;
                uialert(fig,"Failed to save data to the folder","Write Error")
            end
        end

        % Button pushed function: StartAutoCalibrationButton
        function StartAutoCalibrationButtonPushed(app, event)
            if app.Working
                return;
            end
            app.startAutoCalibration();
            for c = app.CalibrationStatusTree.Children
                delete(c);
            end

            % 断っておくと本来ならイベントキューとかつかって設計するべきだと思います
            % でもこの言語にメインスレッドへのディスパッチャはもちろんコンカレントキュー(というかキューそのもの)やGUI提供のイベントループなんか存在しない(はず
            % なので自分の技術力じゃこうするしかありませんでした汚い上非効率だけど許して
            
            app.calibration_etlScan_Start(@() app.calibration_testAffineTransform(@app.calibration_scanWeightMap_Start));
            
        end

        % Button pushed function: StopAutoCalibrationButton
        function StopAutoCalibrationButtonPushed(app, event)
            app.StopAutoCalibration();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.AutoTab = uitab(app.TabGroup);
            app.AutoTab.Title = 'Auto';
            app.GridLayout6 = uigridlayout(app.AutoTab);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.StatusPanel = uipanel(app.GridLayout7);
            app.StatusPanel.Title = 'Status';
            app.CalibrationStatusTree = uitree(app.StatusPanel);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.StartAutoCalibrationButton = uibutton(app.GridLayout8, 'push');
            app.StartAutoCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @StartAutoCalibrationButtonPushed, true);
            app.StartAutoCalibrationButton.Text = 'Start';
            app.StopAutoCalibrationButton = uibutton(app.GridLayout8, 'push');
            app.StopAutoCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @StopAutoCalibrationButtonPushed, true);
            app.StopAutoCalibrationButton.Enable = 'off';
            app.StopAutoCalibrationButton.Text = 'Stop';
            app.GridLayout10 = uigridlayout(app.GridLayout6);
            app.AutoCalibrationStatusAxes = uiaxes(app.GridLayout10);
            xlabel(app.AutoCalibrationStatusAxes, 'X')
            ylabel(app.AutoCalibrationStatusAxes, 'Y')
            zlabel(app.AutoCalibrationStatusAxes, 'Z')
            app.AutoCalibrationStatusImage = uiimage(app.GridLayout10);
            app.ETLScanTab = uitab(app.TabGroup);
            app.ETLScanTab.Title = 'ETL Scan';
            app.CoordinateCorrectionTab = uitab(app.TabGroup);
            app.CoordinateCorrectionTab.Title = 'Coordinate Correction';
            app.WeightMapTab = uitab(app.TabGroup);
            app.WeightMapTab.Title = 'Weight Map';
            app.ParametersPanel = uipanel(app.GridLayout);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout3 = uigridlayout(app.ParametersPanel);
            app.LaserPanel = uipanel(app.GridLayout3);
            app.LaserPanel.Title = 'Laser';
            app.GridLayout4 = uigridlayout(app.LaserPanel);
            app.WaveLengthnmLabel = uilabel(app.GridLayout4);
            app.WaveLengthnmLabel.Text = {'Wave Length'; '(nm)'};
            app.WaveLengthSpinner = uispinner(app.GridLayout4);
            app.WaveLengthSpinner.Step = 0.5;
            app.WaveLengthSpinner.Limits = [1 Inf];
            app.WaveLengthSpinner.Value = 920;
            app.OpticalSystemPanel = uipanel(app.GridLayout3);
            app.OpticalSystemPanel.Title = 'Optical System';
            app.GridLayout4_2 = uigridlayout(app.OpticalSystemPanel);
            app.FocalLengthoflensmLabel = uilabel(app.GridLayout4_2);
            app.FocalLengthoflensmLabel.Text = {'Focal Length'; 'of lens'; '(μm)'};
            app.FocalLengthSpinner = uispinner(app.GridLayout4_2);
            app.FocalLengthSpinner.Step = 0.5;
            app.FocalLengthSpinner.Limits = [1 Inf];
            app.FocalLengthSpinner.Value = 8000;
            app.SLMPanel = uipanel(app.GridLayout3);
            app.SLMPanel.Title = 'SLM';
            app.GridLayout5 = uigridlayout(app.SLMPanel);
            app.PixelPitchmLabel = uilabel(app.GridLayout5);
            app.PixelPitchmLabel.Text = 'Pixel Pitch (μm)';
            app.PixelWidthLabel = uilabel(app.GridLayout5);
            app.PixelWidthLabel.Text = 'Pixel Width';
            app.PixelHeightLabel = uilabel(app.GridLayout5);
            app.PixelHeightLabel.Text = 'Pixel Height';
            app.SLMPixelPitchLabel = uilabel(app.GridLayout5);
            app.SLMPixelPitchLabel.Text = '0';
            app.SLMWidthLabel = uilabel(app.GridLayout5);
            app.SLMWidthLabel.Text = '0';
            app.SLMHeightLabel = uilabel(app.GridLayout5);
            app.SLMHeightLabel.Text = '0';
            app.SavedFolderPanel = uipanel(app.GridLayout3);
            app.SavedFolderPanel.Title = 'Saved Folder';
            app.GridLayout5_2 = uigridlayout(app.SavedFolderPanel);
            app.FolderOpenButton = uibutton(app.GridLayout5_2, 'push');
            app.FolderOpenButton.ButtonPushedFcn = createCallbackFcn(app, @FolderOpenButtonPushed, true);
            app.FolderOpenButton.Text = 'Open';
            app.FolderSaveButton = uibutton(app.GridLayout5_2, 'push');
            app.FolderSaveButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSaveButtonPushed, true);
            app.FolderSaveButton.Text = 'Save';
            app.CurrentFolderTextArea = uitextarea(app.GridLayout5_2);
            app.FolderSelectButton = uibutton(app.GridLayout5_2, 'push');
            app.FolderSelectButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSelectButtonPushed, true);
            app.FolderSelectButton.Text = 'Select';
            app.CalibrationPatternPanel = uipanel(app.GridLayout3);
            app.CalibrationPatternPanel.Title = 'Calibration Pattern';
            app.GridLayout9 = uigridlayout(app.CalibrationPatternPanel);
            app.FirstSpotXmLabel = uilabel(app.GridLayout9);
            app.FirstSpotXmLabel.Text = 'First Spot X (μm)';
            app.FirstSpotYmLabel = uilabel(app.GridLayout9);
            app.FirstSpotYmLabel.Text = 'First Spot Y (μm)';
            app.PatternScaleLabel = uilabel(app.GridLayout9);
            app.PatternScaleLabel.Text = 'Pattern Scale';
            app.FirstCalibSpotXSpinner = uispinner(app.GridLayout9);
            app.FirstCalibSpotXSpinner.Value = 50;
            app.FirstCalibSpotYSpinner = uispinner(app.GridLayout9);
            app.FirstCalibSpotYSpinner.Value = 50;
            app.CalibPatternScaleSpinner = uispinner(app.GridLayout9);
            app.CalibPatternScaleSpinner.Limits = [0 1000];
            app.CalibPatternScaleSpinner.Value = 15;
            app.CalibrationParametersPanel = uipanel(app.GridLayout);
            app.CalibrationParametersPanel.Title = 'Calibration Parameters';
            app.GridLayout2 = uigridlayout(app.CalibrationParametersPanel);
            app.ETLCurrentRawValuePanel = uipanel(app.GridLayout2);
            app.ETLCurrentRawValuePanel.Title = 'ETL Current (Raw Value)';
            app.ETLCurrentLabel = uilabel(app.ETLCurrentRawValuePanel);
            app.ETLCurrentLabel.Text = 'None';
            app.CoordinateTransformPanel = uipanel(app.GridLayout2);
            app.CoordinateTransformPanel.Title = 'Coordinate Transform';
            app.TransformParamLabel = uilabel(app.CoordinateTransformPanel);
            app.TransformParamLabel.Text = 'None';
            app.WeightMapPanel = uipanel(app.GridLayout2);
            app.WeightMapPanel.Title = 'Weight Map';
            app.WeightMapImage = uiimage(app.WeightMapPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AutoCalibrator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end